classdef USV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USVAppAntonnGazdaUIFigure       matlab.ui.Figure
        Tabs                            matlab.ui.container.TabGroup
        Tab_Files                       matlab.ui.container.Tab
        Grid_Files                      matlab.ui.container.GridLayout
        Button_SelectUSVFile            matlab.ui.control.Button
        Field_SelectUSVFile             matlab.ui.control.EditField
        Button_SelectLabelFile          matlab.ui.control.Button
        Field_SelectLabelFile           matlab.ui.control.EditField
        Button_LoadFiles                matlab.ui.control.Button
        Tab_Visualisation               matlab.ui.container.Tab
        Grid_Visualisation              matlab.ui.container.GridLayout
        Field_SegmentStart              matlab.ui.control.NumericEditField
        Field_SegmentLength             matlab.ui.control.NumericEditField
        Button_Plot                     matlab.ui.control.Button
        SegmentLengthsEditFieldLabel    matlab.ui.control.Label
        SegmentStartsEditFieldLabel     matlab.ui.control.Label
        UIAxes_Spectrogram              matlab.ui.control.UIAxes
        UIAxes_USV                      matlab.ui.control.UIAxes
        Tab_Settings                    matlab.ui.container.Tab
        BreakpointButton                matlab.ui.control.Button
        Subtabs_Settings                matlab.ui.container.TabGroup
        Subtab_Visualisation            matlab.ui.container.Tab
        Grid_VisualisationSettings      matlab.ui.container.GridLayout
        ColormapDropDownLabel           matlab.ui.control.Label
        WindowSizeLabel_3               matlab.ui.control.Label
        Label_SpectrogramSettings       matlab.ui.control.Label
        Field_Spectrogram_WindowSize    matlab.ui.control.NumericEditField
        Field_Spectrogram_WindowOverlap  matlab.ui.control.NumericEditField
        Field_Spectrogram_FrequencyMin  matlab.ui.control.NumericEditField
        Field_Spectrogram_FrequencyMax  matlab.ui.control.NumericEditField
        WindowSizeLabel_2               matlab.ui.control.Label
        WindowOverlapLabel              matlab.ui.control.Label
        Field_Spectrogram_Colormap      matlab.ui.control.DropDown
        WindowSizeLabel                 matlab.ui.control.Label
        Tab_Info                        matlab.ui.container.Tab
        Grid_Info                       matlab.ui.container.GridLayout
        Label_AppInfo                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        USV_Signal_path     % path where USV_Signal was loaded
      
        USV_Label_path % Description
        
        USV_Signal % Description
        fs

        SegmentStartIndex
        SegmentStopIndex
        USVSegment


    end

    
    methods (Access = private)
        
        
    function labelsStruct = importLabels(labelPath, fs)
        % Function to import label data from a text file and store it in a struct array.
        %
        % Args:
        %   labelPath (string): The path to the label file to be imported.
        %   fs (double): The sampling rate of the audio file.
        %
        % Returns:
        %   labelsStruct (struct array): A struct array where each struct contains
        %   start time, end time, start frequency, end frequency, label, start index, and stop index.
    
        % Define import options
        opts = delimitedTextImportOptions("NumVariables", 3);
    
        % Specify range and delimiter
        opts.DataLines = [1, Inf];
        opts.Delimiter = ["\t", "\\"];
    
        % Specify column names and types
        opts.VariableNames = ["A", "B", "C"];
        opts.VariableTypes = ["string", "string", "string"];
    
        % Specify file level properties
        opts.ExtraColumnsRule = "ignore";
        opts.EmptyLineRule = "read";
        opts.ConsecutiveDelimitersRule = "join";
    
        % Import the data
        tbl = readmatrix(labelPath, opts);
    
        % Extract times, frequencies, and label names
        labelTimes = str2double(tbl(1:2:end, 1:2)); % Start and end times
        labelsFrequencies = str2double(tbl(2:2:end, 2:3)); % Frequencies
        labelsNames = (tbl(1:2:end, 3)); % Labels (names)
    
        % Initialize struct array
        numLabels = size(labelTimes, 1);
        labelsStruct(numLabels) = struct('StartTime', [], 'EndTime', [], 'Label', '', ...
            'StartFrequency', [], 'EndFrequency', [], 'StartIndex', [], 'StopIndex', []); % Preallocation
    
        % Populate the struct array
        for i = 1:numLabels
            % Calculate start and stop indices based on sampling rate
            startIndex = round(labelTimes(i, 1) * fs); % Convert start time to sample index
            stopIndex = round(labelTimes(i, 2) * fs);  % Convert end time to sample index
            
            % Fill the struct
            labelsStruct(i).StartTime = labelTimes(i, 1); % Start time
            labelsStruct(i).EndTime = labelTimes(i, 2);   % End time
            labelsStruct(i).StartIndex = startIndex;       % Start index in samples
            labelsStruct(i).StopIndex = stopIndex;         % Stop index in samples
            labelsStruct(i).Label = labelsNames{i};       % Label name
            labelsStruct(i).StartFrequency = labelsFrequencies(i, 1); % Start frequency
            labelsStruct(i).EndFrequency = labelsFrequencies(i, 2);   % End frequency
            
        end
    
        % Clear temporary variables
        clear opts tbl labelTimes labelsFrequencies labelsNames
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_SelectUSVFile
        function Button_SelectUSVFilePushed(app, event)
            
            [USV_Signal_filename, USV_Signal_location] = uigetfile('*.wav');
            app.Field_SelectUSVFile.Value = [USV_Signal_location,USV_Signal_filename];
            app.Field_SelectUSVFile.Value = app.USV_Signal_path;

        end

        % Callback function
        function testingLoadpathsButtonPushed(app, event)

        end

        % Button pushed function: Button_LoadFiles
        function Button_LoadFilesPushed(app, event)
            
        [app.USV_Signal, app.fs] = audioread(app.Field_SelectUSVFile.Value);

        % app.USV_Labels = importLabels(app.l)
            
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)


        app.SegmentStartIndex = round(app.Field_SegmentStart.Value * app.fs) + 1;
        app.SegmentStopIndex = round((app.Field_SegmentStart.Value+app.Field_SegmentLength.Value) * app.fs);

        startIndex = app.SegmentStartIndex;
        stopIndex = app.SegmentStopIndex;


        app.USVSegment =  app.USV_Signal(startIndex:stopIndex);

    % Create a time vector for the segment (in seconds)
    segmentTime = (startIndex:stopIndex) / app.fs;

    % Plot the waveform of the audio segment

    
    plot(app.UIAxes_USV, segmentTime, app.USVSegment);

 
    axis(app.UIAxes_USV,'tight')
    grid(app.UIAxes_USV,"on")


% ==================== Spectrogram Calculation and Plotting ====================
% Define spectrogram parameters
windowSize = app.Field_Spectrogram_WindowSize.Value;
overlap = app.Field_Spectrogram_WindowOverlap.Value;
% Compute spectrogram
[~, f, t, p] = spectrogram(app.USVSegment, windowSize, overlap, [], app.fs, 'yaxis');

% Convert frequency to kHz
f_kHz = f / 1000;

% Ensure `p` is in the right format
p_dB = 10 * log10(abs(p) + eps); % Add a small value `eps` to avoid log(0)

% Correct `imagesc` usage
imagesc(app.UIAxes_Spectrogram, t, f_kHz, p_dB);

% Set axis properties
axis(app.UIAxes_Spectrogram, 'xy');
ylim(app.UIAxes_Spectrogram, [app.Field_Spectrogram_FrequencyMin.Value app.Field_Spectrogram_FrequencyMax.Value]); % Ultrasonic range
colormap(app.UIAxes_Spectrogram, app.Field_Spectrogram_Colormap.Value);
colorbar(app.UIAxes_Spectrogram, "off");

% ==================== Overlay Labels on Spectrogram ====================

% for i = 1:length(labels)
%     % Only plot labels within the specified time segment
%     if labels(i).StartTime >= startTime && labels(i).EndTime <= endTime
%         labelStart = labels(i).StartTime - startTime;
%         labelEnd = labels(i).EndTime - startTime;
% 
%         % Plot vertical lines at label start and end times
%         line([labelStart, labelStart], [0, 120], 'Color', 'r', 'LineWidth', 1, 'LineStyle', '--');
%         line([labelEnd, labelEnd], [0, 120], 'Color', 'r', 'LineWidth', 1, 'LineStyle', '--');
% 
%         % Display label text centered between the lines
%         text((labelStart+labelEnd)/2, 100, labels(i).Label, 'Color', 'red', 'FontSize', 8, ...
%              'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'center');
%     end
% end


        end

        % Button pushed function: BreakpointButton
        function BreakpointButtonPushed(app, event)
           x = 0;
        end

        % Value changed function: Field_SelectLabelFile
        function Field_SelectLabelFileValueChanged(app, event)
            value = app.Field_SelectLabelFile.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.USVAppAntonnGazdaUIFigure = uifigure('Visible', 'off');
            app.USVAppAntonnGazdaUIFigure.Name = 'USV App | Antonín Gazda';
            app.Tabs = uitabgroup(app.USVAppAntonnGazdaUIFigure);
            app.Tab_Files = uitab(app.Tabs);
            app.Tab_Files.Title = 'Files';
            app.Grid_Files = uigridlayout(app.Tab_Files);
            app.Button_LoadFiles = uibutton(app.Grid_Files, 'push');
            app.Button_LoadFiles.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadFilesPushed, true);
            app.Button_LoadFiles.Text = 'Load files';
            app.Field_SelectLabelFile = uieditfield(app.Grid_Files, 'text');
            app.Field_SelectLabelFile.ValueChangedFcn = createCallbackFcn(app, @Field_SelectLabelFileValueChanged, true);
            app.Field_SelectLabelFile.Placeholder = 'Path to Label File';
            app.Field_SelectLabelFile.Value = '/Users/gazda/Documents/CTU/Masters/Masters thesis/data/labels/LPS-SI2homo-mH02-I04-USV-IVojt.txt';
            app.Button_SelectLabelFile = uibutton(app.Grid_Files, 'push');
            app.Button_SelectLabelFile.Text = 'Select Label File (.txt)';
            app.Field_SelectUSVFile = uieditfield(app.Grid_Files, 'text');
            app.Field_SelectUSVFile.Placeholder = 'Path to USV File';
            app.Field_SelectUSVFile.Value = '/Users/gazda/Documents/CTU/Masters/Masters thesis/data/usv_audio/denoise/LPS-SI2homo-mH02-I04-USV_denoise.wav';
            app.Button_SelectUSVFile = uibutton(app.Grid_Files, 'push');
            app.Button_SelectUSVFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectUSVFilePushed, true);
            app.Button_SelectUSVFile.Text = 'Select USV file (.wav)';
            app.Tab_Visualisation = uitab(app.Tabs);
            app.Tab_Visualisation.Title = 'Visualisation';
            app.Grid_Visualisation = uigridlayout(app.Tab_Visualisation);
            app.UIAxes_USV = uiaxes(app.Grid_Visualisation);
            title(app.UIAxes_USV, 'USV - Time')
            xlabel(app.UIAxes_USV, 'Time (s)')
            ylabel(app.UIAxes_USV, 'Amplitude (-)')
            zlabel(app.UIAxes_USV, 'Z')
            app.UIAxes_Spectrogram = uiaxes(app.Grid_Visualisation);
            title(app.UIAxes_Spectrogram, 'USV - Spectrogram')
            xlabel(app.UIAxes_Spectrogram, 'Time (s)')
            ylabel(app.UIAxes_Spectrogram, 'Frequency (kHz)')
            zlabel(app.UIAxes_Spectrogram, 'Z')
            app.SegmentStartsEditFieldLabel = uilabel(app.Grid_Visualisation);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentLengthsEditFieldLabel = uilabel(app.Grid_Visualisation);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.Button_Plot = uibutton(app.Grid_Visualisation, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Text = 'Plot';
            app.Field_SegmentLength = uieditfield(app.Grid_Visualisation, 'numeric');
            app.Field_SegmentLength.Value = 10;
            app.Field_SegmentStart = uieditfield(app.Grid_Visualisation, 'numeric');
            app.Field_SegmentStart.Value = 60;
            app.Tab_Settings = uitab(app.Tabs);
            app.Tab_Settings.Title = 'Settings';
            app.Subtabs_Settings = uitabgroup(app.Tab_Settings);
            app.Subtab_Visualisation = uitab(app.Subtabs_Settings);
            app.Subtab_Visualisation.Title = 'Visualisation';
            app.Grid_VisualisationSettings = uigridlayout(app.Subtab_Visualisation);
            app.WindowSizeLabel = uilabel(app.Grid_VisualisationSettings);
            app.WindowSizeLabel.Text = 'Window Size  (-)';
            app.Field_Spectrogram_Colormap = uidropdown(app.Grid_VisualisationSettings);
            app.Field_Spectrogram_Colormap.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'winter', 'gray', 'jet'};
            app.Field_Spectrogram_Colormap.Value = 'hot';
            app.WindowOverlapLabel = uilabel(app.Grid_VisualisationSettings);
            app.WindowOverlapLabel.Text = 'Window Overlap  (-)';
            app.WindowSizeLabel_2 = uilabel(app.Grid_VisualisationSettings);
            app.WindowSizeLabel_2.Text = 'Frequency Min  (kHz)';
            app.Field_Spectrogram_FrequencyMax = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_FrequencyMax.Value = 120;
            app.Field_Spectrogram_FrequencyMin = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_FrequencyMin.Value = 40;
            app.Field_Spectrogram_WindowOverlap = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_WindowOverlap.Value = 512;
            app.Field_Spectrogram_WindowSize = uieditfield(app.Grid_VisualisationSettings, 'numeric');
            app.Field_Spectrogram_WindowSize.Value = 1024;
            app.Label_SpectrogramSettings = uilabel(app.Grid_VisualisationSettings);
            app.Label_SpectrogramSettings.Text = 'Spectrogram Settings';
            app.WindowSizeLabel_3 = uilabel(app.Grid_VisualisationSettings);
            app.WindowSizeLabel_3.Text = 'Frequency Max  (kHz)';
            app.ColormapDropDownLabel = uilabel(app.Grid_VisualisationSettings);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.BreakpointButton = uibutton(app.Tab_Settings, 'push');
            app.BreakpointButton.ButtonPushedFcn = createCallbackFcn(app, @BreakpointButtonPushed, true);
            app.BreakpointButton.Text = 'Breakpoint';
            app.Tab_Info = uitab(app.Tabs);
            app.Tab_Info.Title = 'Info';
            app.Grid_Info = uigridlayout(app.Tab_Info);
            app.Label_AppInfo = uilabel(app.Grid_Info);
            app.Label_AppInfo.Text = {'Master Thesis: Antonín Gazda'; ''; '2024/25'; ''; 'Supervisor: prof. Ing. Roman Čmejla, CSc.'; ''; 'CTU FEE'};
            % Show the figure after all components are created
            app.USVAppAntonnGazdaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USV_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.USVAppAntonnGazdaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USVAppAntonnGazdaUIFigure)
        end
    end
end